classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MATLABUIFigure     matlab.ui.Figure
        loadPictionButton  matlab.ui.control.Button
        title              matlab.ui.control.Label
        noImgPrompt        matlab.ui.control.Label
        current            matlab.ui.control.Label
        currentStep        matlab.ui.control.Label
        total              matlab.ui.control.Label
        totalStep          matlab.ui.control.Label
        currentModeName    matlab.ui.control.Label
        switchMode         matlab.ui.control.Button
        nextStep           matlab.ui.control.Button
        randomGeneration   matlab.ui.control.Button
    end


    properties (Access = private)
        img % 读入的图片
        array
        currentPos
        totalPos
        matrixButton  matlab.ui.control.Button
        loadSuccess
        emptyLabel
        userMode
        currentArray
        endArray
        stepArray
        successApp
        errorApp
        randomArrays
        randomArrayIndex
    end
    
    methods(Access = public)
        % 求解华容道的算法
        function path=solve(app, numbers, qType)
            %numbers为一维数组,0代表空格,qType为3和4时分别求解3*3和4*4的,返回path为路径,-1代表左移,1代表右移.-3/-4代表上移,3/4代表下移
            if app.hasSolve(numbers) == false
                path = false;
                return;
            end
            if qType == 3
                path = app.solve3(numbers);
            end
            if qType == 4
                path = app.solve4(numbers);
            end
        end
        
        function res=hasSolve(~, numbers)
            % 传入一维数组numbers
            revCnt = 0;
            for i = 1:16
                for j = i + 1:16
                    if (numbers(i) > numbers(j) && numbers(i) * numbers(j) ~= 0)
                        revCnt =revCnt + 1;
                    end
                end
            end
            
            if length(numbers) == 16
                revCnt = revCnt + 3 - floor((find(numbers==0) - 1) / 4);
            end
            if mod(revCnt, 2) == 0
                res = true;
            else
                res = false;
            end
        end
        
        function enqueue(app, element)
            global dataStore;
            if (app.queueIsEmpty())
                dataStore(1) = element;
                return;
            else
                flag = false;
                for i = 1 : length(dataStore)
                    if element.priority < dataStore(i).priority
                        dataStore = app.insert(dataStore, i, element);
                        flag = true;
                        break;
                    end
                end
                if flag == false
                    dataStore(length(dataStore) + 1) = element;
                end
            end
        end
        
        function data=insert(~, mat,ind,num)
            n=length(mat);
            data(ind)=num;
            data(1:ind-1)=mat(1:ind-1);
            data(ind+1:n+1)=mat(ind:n);
        end
        
        function res=queueIsEmpty(app)
            global dataStore;
            if isempty(dataStore)
                res = true;
            else
                res = false;
            end
        end
        
        function res=deque(app)
            global dataStore;
            res = dataStore(1);
            dataStore(1) = [];
        end
        
        function res=calDistance(~, node, w)
            res = 0;
            for i = 1 : length(node)
                if (node(i) == 0)
                    continue;
                end
                res = res + abs(floor((i - 1) / w) - floor((node(i) - 1) / w)) + abs(mod(i - 1, w) - mod(node(i) - 1, w));
            end
        end
        
        function path=solve4(app, board)
            global dataStore;
            target = [1, 2, 3, 4, 5, 6, 7, 8, 9, 10, 11, 12, 13, 14, 15, 0];
            dir = [-1,1,-4,4];
            s0 = app.newState(app.calDistance(board,4), board, find(board==0) - 1, 0, []);
            dataStore = [struct(s0)];
            seen = [app.st2Str(board)];
            while app.queueIsEmpty() == false
                state1 = app.deque();
                if app.st2Str(state1.board) == app.st2Str(target)
                    path = state1.process;
                    return;
                end
                for i = 1:4
                    nei = state1.pos0 + dir(i);
                    if abs(floor(nei / 4) - floor(state1.pos0 / 4)) + abs(mod(nei, 4) - mod(state1.pos0, 4)) ~= 1
                        continue;
                    end
                    if nei >= 0 && nei < 16
                        newboard = state1.board;
                        temp = newboard(nei + 1);
                        newboard(nei + 1) = newboard(state1.pos0 + 1);
                        newboard(state1.pos0 + 1) = temp;
                        if isempty(find(seen==app.st2Str(newboard), 1))
                            new_state = app.newState(state1.depth+1+0.5 * app.calDistance(newboard,4), newboard, nei, state1.depth+1, [state1.process, dir(i)]);
                            app.enqueue(new_state);
                            seen = [seen, app.st2Str(new_state.board)];
                        end
                    end
                end
            end
            path = [];
        end
        
        function path=solve3(app, board)
            global dataStore;
            target = [1, 2, 3, 4, 5, 6, 7, 8, 0];
            dir = [-1,1,-3,3];
            s0 = app.newState(app.calDistance(board,3), board, find(board==0) - 1, 0, []);
            dataStore = [struct(s0)];
            seen = [app.st2Str(board)];
            while app.queueIsEmpty() == false
                state1 = app.deque();
                if app.st2Str(state1.board) == app.st2Str(target)
                    path = state1.process;
                    return;
                end
                for i = 1:4
                    nei = state1.pos0 + dir(i);
                    if abs(floor(nei / 3) - floor(state1.pos0 / 3)) + abs(mod(nei, 3) - mod(state1.pos0, 3)) ~= 1
                        continue;
                    end
                    if nei >= 0 && nei < 9
                        newboard = state1.board;
                        temp = newboard(nei + 1);
                        newboard(nei + 1) = newboard(state1.pos0 + 1);
                        newboard(state1.pos0 + 1) = temp;
                        if isempty(find(seen==app.st2Str(newboard), 1))
                            new_state = app.newState(state1.depth+1+0.9 * app.calDistance(newboard,3), newboard, nei, state1.depth+1, [state1.process, dir(i)]);
                            app.enqueue(new_state);
                            seen = [seen, app.st2Str(new_state.board)];
                        end
                    end
                end
            end
            path = [];
        end
        
        function res=newState(~, priority, board, pos0, depth, process)
            res = struct;
            res.priority = priority;
            res.board = board;
            res.pos0 = pos0;
            res.depth = depth;
            res.process = process;
        end
        
        function res=st2Str(~, board)
            res = num2str(board);
            res = strrep(res, ' ', '');
            res = string(res);
        end
        
    end
    
    % 分割线 ---------------------------------------------------------------------------
    % 分割线 ---------------------------------------------------------------------------
    % 分割线 ---------------------------------------------------------------------------
    
    methods (Access = public)
        function translateImg(app)
            % TODO
            % m = randperm(16, 16) - 1;
            % app.currentArray = reshape(m, 4, 4);
            load cnn.mat net;
            app.currentArray = classifyNums(net, app.img, 4, 4);
            % app.currentArray
        end
        
        function showEndInfo(app, originIsEnd)
            pause(1);
            app.successApp = finish(app, app.userMode, originIsEnd);
        end
        
        function ret = clickedPosMovable(app, x, y)
            [x0, y0] = find(app.currentArray == 0);
            if (x == x0) && ((y - y0 == 1) || (y0 - y == 1))
                ret = true;
            elseif  (y == y0) && ((x - x0 == 1) || (x0 - x == 1))
                ret = true;
            else
                ret = false;
            end
        end
        
        function move(app, x, y)
            [x0, y0] = find(app.currentArray == 0);
            if x < 0 || y < 0
                % 自动求解模式下的移动
                direction = app.stepArray(app.currentPos);
                if direction == -1
                    % 左移
                    x = x0;
                    y = y0 - 1;
                elseif direction == 1
                    % 右移
                    x = x0;
                    y = y0 + 1;
                elseif direction == -4
                    % 上移
                    x = x0 - 1;
                    y = y0;
                else
                    % 下移
                    x = x0 + 1;
                    y = y0;
                end
                app.currentArray(x0, y0) = app.currentArray(x, y);
                app.matrixButton(x0, y0).Text = app.matrixButton(x, y).Text;
                app.currentArray(x, y) = 0;
                app.matrixButton(x, y).Text = '';
            elseif app.clickedPosMovable(x, y)  % 用户模式，且点击位置可移动
                app.currentArray(x0, y0) = app.currentArray(x, y);
                app.matrixButton(x0, y0).Text = app.matrixButton(x, y).Text;
                app.currentArray(x, y) = 0;
                app.matrixButton(x, y).Text = '';
            end
        end
        
        function ret = judgeEnd(app)
            ret = isequal(app.currentArray, app.endArray);
        end
        
        function print(app)
            fprintf("[");
            for i = 1:4
                for j = 1:4
                    fprintf("%d ", app.currentArray(i, j));
                end
                fprintf(";");
            end
            disp("]");
        end
        
        function next(app, event)
            if app.userMode
                text = event.Source.Text;
                num = 0;
                if text ~= ""
                    num = str2num(text);
                end
                [x, y] = find(app.currentArray == num);
                % disp(event.Source.Text);
                % disp(x);
                % disp(y);
                app.move(x, y);
                if app.judgeEnd()
                    app.showEndInfo(false);
                end
            else
                if app.currentPos <= app.totalPos - 1
                    app.move(-1, -1);
                    app.currentPos = 1 + app.currentPos;
                    app.setStep(app.currentPos, app.totalPos);
                    
%                     for i=1:4
%                         for j=1:4
%                             value = app.cuurentArray(i, j);
%                             if value == 0
%                                 app.matrixButton(i,j).Text = '';
%                             else
%                                 app.matrixButton(i,j).Text = num2str(value);
%                             end
%                         end
%                     end
                    if app.currentPos == app.totalPos
                        app.showEndInfo(false);
                    end
                end
                % app.print();
            end
        end
        
        function showMatrixButton(app)
            for i=1:4
                for j=1:4
                    app.matrixButton(i,j).Visible = true;
                end
            end
        end
        
        function hideStep(app)
            app.total.Visible = false;
            app.totalStep.Visible = false;
            app.current.Visible = false;
            app.currentStep.Visible = false;
        end
        
        function showStep(app)
            app.total.Visible = true;
            app.totalStep.Visible = true;
            app.current.Visible = true;
            app.currentStep.Visible = true;
        end
        
        function hideMatrixButton(app)
            for i=1:4
                for j=1:4
                    app.matrixButton(i,j).Visible = false;
                end
            end
        end
        
        function setStep(app, cur, total)
            app.currentStep.Text = num2str(cur);
            app.totalStep.Text = num2str(total);
        end
        
        function userMode2autoMode(app)
            a = [1, 2, 3, 4; 5, 6, 7, 8; 9, 10, 11, 12; 13, 14, 0, 15];
            b = [1, 2, 3, 4; 5, 6, 7, 8; 9, 10, 11, 12; 13, 14, 15, 0];
            app.array = cell(1, 2);
            app.array{1,1} = a;
            app.array{1,2} = b;
            
            app.userMode = false;
            app.currentPos = 1;
            app.totalPos = length(app.array);
            app.setStep(1, app.totalPos);
            app.showStep();
            firstMatrix = app.array{1, app.currentPos};
            for i=1:4
                for j=1:4
                    if firstMatrix(i, j) == 0
                        app.matrixButton(i,j).Text = '';
                    else
                        app.matrixButton(i,j).Text = num2str(firstMatrix(i, j));
                    end
                    app.matrixButton(i,j).Visible = true;
                end
            end
        end
        
        function setNoSolution(app)
            app.errorApp = errorMsg(app);
        end
        
        % 上传完图片后最开始的矩阵界面 (此时处于用户模式)
        function displayMatrix(app)
            app.hideStep();
            for i=1:4
                for j=1:4
                    if app.currentArray(i, j) == 0
                        app.matrixButton(i, j).Text = '';
                    else
                        app.matrixButton(i, j).Text = num2str(app.currentArray(i, j));
                    end
                    app.matrixButton(i,j).Visible = true;
                end
            end
            if app.judgeEnd()
                 app.showEndInfo(true);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.hideStep();
            app.endArray = [1, 2, 3, 4; 5, 6, 7, 8; 9, 10, 11, 12; 13, 14, 15, 0];
            
            app.switchMode.Visible = false;
            app.nextStep.Visible = false;
            app.currentModeName.Visible = false;
            
            for i=1:4
                for j=1:4
                    app.matrixButton(i,j) = uibutton(app.MATLABUIFigure,'push');
                    app.matrixButton(i,j).ButtonPushedFcn = createCallbackFcn(app, @next, true);
                    app.matrixButton(i,j).BackgroundColor = [0.675, 0.867, 0.871];
                    app.matrixButton(i,j).Position = [-20 + j*100 450-i*100 95 95];
                    app.matrixButton(i,j).Text = '';
                    app.matrixButton(i,j).FontName = 'Segoe UI Historic';
                    app.matrixButton(i,j).FontSize = 40;
                    app.matrixButton(i,j).FontColor = [1,1,1];
                    app.matrixButton(i,j).Tag = num2str(sub2ind([4 4],i,j));
                    app.matrixButton(i,j).Visible = false;
                end
            end
            % app.matrixButton.Visible = false;
            app.randomArrayIndex = 1;
            app.randomArrays = {[0 1 2 3 ;5 6 7 4 ;9 10 11 8 ;13 14 15 12] 
                [1 6 2 3 ;5 10 7 4 ;9 0 11 8 ;13 14 15 12]
                [2 7 0 3 ;1 6 11 4 ;5 9 15 8 ;13 10 14 12]
                [1 6 2 3 ;5 10 7 0 ;9 11 8 4 ;13 14 15 12]
                [0 2 7 3 ;1 6 11 4 ;5 9 15 8 ;13 10 14 12]
                [1 6 2 3 ;5 10 7 4 ;9 14 0 8 ;13 15 11 12]
                [1 2 7 3 ;5 6 11 4 ;9 10 15 8 ;13 14 0 12]
                [1 6 2 3 ;0 5 10 4 ;9 14 7 8 ;13 15 11 12]
                [1 2 0 3 ;5 6 10 4 ;9 14 7 8 ;13 15 11 12]};
            
        end

        % Button pushed function: loadPictionButton
        function loadPictionButtonPushed(app, event)
            
            [f, p]=uigetfile({'*.jpg;*.tif;*.png;*.gif;*.bmp','All Image Files'});
            if (ischar(p))
                % app.loadPictionButton.Enable = false;
                w = waitbar(0, "加载图片中......");
                setappdata(w, "WindowStyle", "modal");
                % disp("#DEBUG MSG: Read image successfully!");
                app.userMode = true;  % 用户模式，用户可以自己玩
                fname = [p f];
                waitbar(0.1, w, "加载图片中......");
                app.img = imread(fname);  %读取图片，图片名字为img
                waitbar(0.2, w, "识别图片信息中......");
                
                app.translateImg();
                waitbar(0.5, w, "识别图片信息中......");
                pause(0.3);
                waitbar(0.8, w, "识别成功，设置GUI信息中......");
                pause(0.8);
                waitbar(1, w, "GUI设置完成！");
                pause(0.3);
                close(w);
                
                app.noImgPrompt.Visible = false;
                app.switchMode.Visible = true;
                app.nextStep.Visible = false;
                app.currentModeName.Text = "当前为用户模式";
                app.currentModeName.Visible = true;
                
                app.displayMatrix();
                % app.loadPictionButton.Enable = true;
            end
        end

        % Button pushed function: switchMode
        function switchModeButtonPushed(app, event)
            if app.userMode
                app.userMode = false;
                
                app.currentModeName.Text = "当前为自动求解模式";
                app.currentModeName.Visible = true;
                app.nextStep.Visible = true;
                
                app.currentPos = 1;
                arg = reshape(app.currentArray', 1, 16);
                % disp("before calling solve, argument: ");
                % disp(arg);
                app.stepArray = app.solve(arg, 4);
                % disp("after calling solve, ret: ");
                % disp(app.stepArray);
                if (length(app.stepArray) == 1) && (app.stepArray(1) == false)
                    % 无解!!!!!!!!!!!!!!
                    app.setNoSolution();
                else
                    app.totalPos = length(app.stepArray) + 1;
                    app.setStep(app.currentPos, app.totalPos);
                    app.showStep();
                end
            else
                app.hideStep();
                app.userMode = true;
                
                app.currentModeName.Text = "当前为用户模式";
                app.currentModeName.Visible = true;
                app.nextStep.Visible = false;
            end
        end

        % Button pushed function: nextStep
        function nextStepButtonPushed(app, event)
            app.userMode = false;
            app.next(event);
        end

        % Close request function: MATLABUIFigure
        function MATLABUIFigureCloseRequest(app, event)
            delete(app.successApp);
            delete(app);
        end

        % Button pushed function: randomGeneration
        function randomGenerationButtonPushed(app, event)
            % app.currentArray = reshape(randperm(16,16) - 1, 4, 4);
            app.currentArray = app.randomArrays{app.randomArrayIndex};
            % display(app.randomArrays);
            app.randomArrayIndex = app.randomArrayIndex + 1;
            if app.randomArrayIndex > 9
                app.randomArrayIndex = app.randomArrayIndex - 9;
            end
            
            app.noImgPrompt.Visible = false;
            app.switchMode.Visible = true;
            app.nextStep.Visible = false;
            app.userMode = true;
            app.currentModeName.Text = "当前为用户模式";
            app.currentModeName.Visible = true;
            
            
            app.displayMatrix();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MATLABUIFigure = uifigure('Visible', 'off');
            app.MATLABUIFigure.Name = 'MATLAB课程大作业—数字华容道小游戏';
            app.MATLABUIFigure.CloseRequestFcn = createCallbackFcn(app, @MATLABUIFigureCloseRequest, true);
            app.loadPictionButton = uibutton(app.MATLABUIFigure, 'push');
            app.loadPictionButton.ButtonPushedFcn = createCallbackFcn(app, @loadPictionButtonPushed, true);
            app.loadPictionButton.Text = '上传图片';
            app.title = uilabel(app.MATLABUIFigure);
            app.title.Text = '数字华容道识别与求解';
            app.noImgPrompt = uilabel(app.MATLABUIFigure);
            app.noImgPrompt.Text = {'欢迎使用数字华容道小游戏！'; '您可以通过点击右下角'; '"上传图片"/"随机生成"按钮'; '上传数字华容道图片或'; '随机生成华容道！'};
            app.current = uilabel(app.MATLABUIFigure);
            app.current.Text = '当前步骤：';
            app.currentStep = uilabel(app.MATLABUIFigure);
            app.currentStep.Text = '';
            app.total = uilabel(app.MATLABUIFigure);
            app.total.Text = '总步骤：';
            app.totalStep = uilabel(app.MATLABUIFigure);
            app.totalStep.Text = '';
            app.currentModeName = uilabel(app.MATLABUIFigure);
            app.currentModeName.Text = '当前为自动求解模式';
            app.switchMode = uibutton(app.MATLABUIFigure, 'push');
            app.switchMode.ButtonPushedFcn = createCallbackFcn(app, @switchModeButtonPushed, true);
            app.switchMode.Text = '切换模式';
            app.nextStep = uibutton(app.MATLABUIFigure, 'push');
            app.nextStep.ButtonPushedFcn = createCallbackFcn(app, @nextStepButtonPushed, true);
            app.nextStep.Text = '下一步';
            app.randomGeneration = uibutton(app.MATLABUIFigure, 'push');
            app.randomGeneration.ButtonPushedFcn = createCallbackFcn(app, @randomGenerationButtonPushed, true);
            app.randomGeneration.Text = '随机生成';
            % Show the figure after all components are created
            app.MATLABUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MATLABUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MATLABUIFigure)
        end
    end
end