classdef finish < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        confirm   matlab.ui.control.Button
        Lamp      matlab.ui.control.Lamp
        msg       matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, userMode, originIsEnd)
            % mainapp: 主应用
            % userMode: true -> 用户模式, false -> 自动求解模式
            % originIsEnd: 一开始上传的图片是否就是终止状态
            app.CallingApp = mainapp;
            
            if originIsEnd
                app.msg.Text = "该华容道无需求解！";
            elseif userMode
                app.msg.Text = "恭喜你，求解成功！";
            else
                app.msg.Text = "自动求解完成！";
            end
        end

        % Button pushed function: confirm
        function confirmButtonPushed(app, event)
            callingApp = app.CallingApp;
            delete(app);
            
            pause(1);
            
            callingApp.hideStep();
            callingApp.currentModeName.Visible = false;
            callingApp.noImgPrompt.Visible = true;
            callingApp.hideMatrixButton();
            callingApp.switchMode.Visible = false;
            callingApp.nextStep.Visible = false;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            callingApp = app.CallingApp;
            delete(app);
            
            pause(1);
            
            callingApp.hideStep();
            callingApp.currentModeName.Visible = false;
            callingApp.noImgPrompt.Visible = true;
            callingApp.hideMatrixButton();
            callingApp.switchMode.Visible = false;
            callingApp.nextStep.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '游戏结束';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.confirm = uibutton(app.UIFigure, 'push');
            app.confirm.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed, true);
            app.confirm.Text = '确认';
            app.Lamp = uilamp(app.UIFigure);
            app.msg = uilabel(app.UIFigure);
            app.msg.Text = '游戏结束！';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finish(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end